classdef FlukeMatlabToolkit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ROIPointsLabel                  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        RowNumToBeDeleted               matlab.ui.control.DropDown
        RowtoBeDeletedLabel             matlab.ui.control.Label
        EnableROIButton                 matlab.ui.control.Button
        DeletePointButton               matlab.ui.control.Button
        ROIPoints                       matlab.ui.control.Table
        AddROIPointsButton              matlab.ui.control.Button
        MovieLength                     matlab.ui.control.NumericEditField
        LengthmSEditFieldLabel          matlab.ui.control.Label
        RecordFileName                  matlab.ui.control.EditField
        FileNameEditFieldLabel_2        matlab.ui.control.Label
        RecordPath                      matlab.ui.control.EditField
        RecordingPathLabel              matlab.ui.control.Label
        BrowseRecordButton              matlab.ui.control.Button
        RecordIS3VideoButton            matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        AlarmTab                        matlab.ui.container.Tab
        MaxTemp                         matlab.ui.control.NumericEditField
        MaxTempEditFieldLabel           matlab.ui.control.Label
        MinTemp                         matlab.ui.control.NumericEditField
        MinTempEditFieldLabel           matlab.ui.control.Label
        EnableAlarmButton               matlab.ui.control.Button
        AlarmLamp                       matlab.ui.control.Lamp
        AlarmLampLabel                  matlab.ui.control.Label
        MaxTempThreshold                matlab.ui.control.NumericEditField
        MaxTempThresholdEditFieldLabel  matlab.ui.control.Label
        MinTempThreshold                matlab.ui.control.NumericEditField
        MinTempThresholdEditFieldLabel  matlab.ui.control.Label
        BooleanTriggerTab               matlab.ui.container.Tab
        BooleanTriggerNumCaptures       matlab.ui.control.NumericEditField
        NumberofCapturesLabel           matlab.ui.control.Label
        BooleanTriggerInterval          matlab.ui.control.NumericEditField
        IntervalBetweenCapturesmSLabel  matlab.ui.control.Label
        BooleanTriggerFileName          matlab.ui.control.EditField
        BooleanTriggerFileNameLabel     matlab.ui.control.Label
        BooleanTriggerPath              matlab.ui.control.EditField
        BooleanTriggerFilePathLabel     matlab.ui.control.Label
        BrowseBooleanTriggerButton      matlab.ui.control.Button
        EnableBooleanTriggerButton      matlab.ui.control.Button
        TemperatureThresholdTriggerTab  matlab.ui.container.Tab
        TempTriggerFileName             matlab.ui.control.EditField
        TempTriggerFileNameLabel        matlab.ui.control.Label
        EnableTempTriggerButton         matlab.ui.control.Button
        TempTriggerNumCaptures          matlab.ui.control.NumericEditField
        NumberofCapturesLabel_2         matlab.ui.control.Label
        TempTriggerInterval             matlab.ui.control.NumericEditField
        IntervalBetweenCapturesmSLabel_2  matlab.ui.control.Label
        TempTriggerMaxTempThreshold     matlab.ui.control.NumericEditField
        MaxTempThresholdLabel           matlab.ui.control.Label
        TempTriggerMinTempThreshold     matlab.ui.control.NumericEditField
        MinTempThresholdEditFieldLabel_2  matlab.ui.control.Label
        TempTriggerFilePath             matlab.ui.control.EditField
        TemperatureThresholdTriggerFilePathLabel  matlab.ui.control.Label
        TempTriggerBrowseButton         matlab.ui.control.Button
        RelativeTimeTriggerTab          matlab.ui.container.Tab
        EnableRelativeTimeTriggerButton  matlab.ui.control.Button
        RelativeTimeTriggerNummS        matlab.ui.control.NumericEditField
        NumberofmStoWaitLabel           matlab.ui.control.Label
        RelativeTimeTriggerNumCaptures  matlab.ui.control.NumericEditField
        NumberofCapturesLabel_3         matlab.ui.control.Label
        RelativeTimeTriggerInterval     matlab.ui.control.NumericEditField
        IntervalBetweenCapturesmSLabel_3  matlab.ui.control.Label
        RelativeTimeTriggerFileName     matlab.ui.control.EditField
        RelativeTimeTriggerFileNameLabel  matlab.ui.control.Label
        RelativeTimeTriggerFilePath     matlab.ui.control.EditField
        RelativeTimeTriggerFilePathLabel  matlab.ui.control.Label
        RelativeTimeTriggerBrowse       matlab.ui.control.Button
        AbsoluteTimeTriggerTab          matlab.ui.container.Tab
        AbsoluteTimeTriggerSecond       matlab.ui.control.NumericEditField
        SecondLabel                     matlab.ui.control.Label
        AbsoluteTimeTriggerMinute       matlab.ui.control.NumericEditField
        MinuteLabel                     matlab.ui.control.Label
        AbsoluteTimeTriggerHour         matlab.ui.control.NumericEditField
        HourLabel                       matlab.ui.control.Label
        AbsoluteTimeTriggerDay          matlab.ui.control.NumericEditField
        DayLabel                        matlab.ui.control.Label
        AbsoluteTimeTriggerMonth        matlab.ui.control.NumericEditField
        MonthLabel                      matlab.ui.control.Label
        AbsoluteTimeTriggerYear         matlab.ui.control.NumericEditField
        YearLabel                       matlab.ui.control.Label
        EnableAbsoluteTimeTriggerButton  matlab.ui.control.Button
        AbsoluteTimeTriggerNumCaptures  matlab.ui.control.NumericEditField
        NumberofCapturesLabel_4         matlab.ui.control.Label
        AbsoluteTimeTriggerInterval     matlab.ui.control.NumericEditField
        IntervalBetweenCapturesmSLabel_4  matlab.ui.control.Label
        AbsoluteTimeTriggerFileName     matlab.ui.control.EditField
        AbsoluteTimeTriggerFileNameLabel  matlab.ui.control.Label
        AbsoluteTimeTriggerFilePath     matlab.ui.control.EditField
        AbsoluteTimeTriggerFilePathLabel  matlab.ui.control.Label
        AbsoluteTimeTriggerBrowse       matlab.ui.control.Button
        WritetoCSVTab                   matlab.ui.container.Tab
        CSVBrowseButton                 matlab.ui.control.Button
        CSVFileName                     matlab.ui.control.EditField
        CSVFileNameLabel                matlab.ui.control.Label
        CSVFilePath                     matlab.ui.control.EditField
        CSVFilePathLabel                matlab.ui.control.Label
        ExporttoCSVButton               matlab.ui.control.Button
        EBTConfigurationPanel           matlab.ui.container.Panel
        ConfigureEBTButton              matlab.ui.control.Button
        EBTTempUnits                    matlab.ui.control.DropDown
        TemperatureUnitsDropDownLabel_2  matlab.ui.control.Label
        EBTTransmission                 matlab.ui.control.NumericEditField
        TransmissionEditFieldLabel      matlab.ui.control.Label
        EBTBGTemp                       matlab.ui.control.NumericEditField
        BGTempEditFieldLabel            matlab.ui.control.Label
        EBTEmissivity                   matlab.ui.control.NumericEditField
        EmissivityEditFieldLabel        matlab.ui.control.Label
        TemperatureUnits                matlab.ui.control.DropDown
        TemperatureUnitsDropDownLabel   matlab.ui.control.Label
        AdjustRangeButton               matlab.ui.control.Button
        SelectedRange                   matlab.ui.control.DropDown
        SelectRangeDropDownLabel        matlab.ui.control.Label
        BrowseScreenshotButton          matlab.ui.control.Button
        ScreenshotFileName              matlab.ui.control.EditField
        FileNameEditFieldLabel          matlab.ui.control.Label
        TakeIS2ScreenshotButton         matlab.ui.control.Button
        ScreenshotPath                  matlab.ui.control.EditField
        ScreenshotPathLabel             matlab.ui.control.Label
        SendNUCCommandButton            matlab.ui.control.Button
        NUCCommands                     matlab.ui.control.DropDown
        NUCControlDropDownLabel         matlab.ui.control.Label
        FocusDistance                   matlab.ui.control.Slider
        FocusDistancemmSliderLabel      matlab.ui.control.Label
        TrackMinMaxTempButton           matlab.ui.control.Button
        CurrentTemp                     matlab.ui.control.NumericEditField
        TempEditFieldLabel              matlab.ui.control.Label
        TrackPointTemperatureButton     matlab.ui.control.Button
        Y                               matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        X                               matlab.ui.control.NumericEditField
        XLabel                          matlab.ui.control.Label
        ApplyEdgeDetectionButton        matlab.ui.control.Button
        EdgeDetectionAlgorithmsListBox  matlab.ui.control.ListBox
        EdgeDetectionAlgorithmsListBoxLabel  matlab.ui.control.Label
        ApplySharpeningButton           matlab.ui.control.Button
        ApplyContrastStretchButton      matlab.ui.control.Button
        StopStreamButton                matlab.ui.control.Button
        StartStream                     matlab.ui.control.Button
        AvailableCameras                matlab.ui.control.ListBox
        AvailableCamerasLabel           matlab.ui.control.Label
        DiscoverDevices                 matlab.ui.control.Button
        TemperatureTracker              matlab.ui.control.UIAxes
        ThermalImage                    matlab.ui.control.UIAxes
    end

    %Above Code is all MATLAB Generated and specifies the GUI components
    properties (Access = private)
        
        %The properties below are used to pass data throughout the application
        contrastStretch;
        sharpening;
        detectEdges;
        TempArray;
        IRImage;
        trackPointTemp;
        trackMinMaxTemp;
        alarmEnable;
        tempTriggerEnable;
        enableROI;
        maskedData;
        maxArray;
        minArray;
        currentTempArray;
        count;
        maxLine;
        minLine;
        pointLine;
        relativeTimeTriggerValid;
        absoluteTimeTriggerValid;
        enableTriggerValid;
        thresholdTriggerValid;
        relativeTimeTriggerEnable;
        absoluteTimeTriggerEnable;
        booleanTriggerEnable;
        addROIPoints;
    end
    
    methods (Access = private)
    
        %This application runs the "Streaming Loop" on a timer that executes at a specified interval (currently every 100 mS)
        %This is the function that the timer executes each iteration. It contains all the function calls and manipulation 
        %that requires streaming data (i.e. updates the image, sends new data to the alarm function, etc.)
        function FrameCallback(app,~, ~)
            
            try
                
                %Get the temperature data and the IR Image from the camera
                [app.TempArray, app.IRImage] = ToolkitFunctions.GetData(app.TemperatureUnits.Value);
                
                %Apply Contrast Stretch to the image
                if app.contrastStretch
                   app.IRImage = imadjust(app.IRImage, stretchlim(app.IRImage));
                   app.ApplyContrastStretchButton.Text = "Contrast Stretch Applied";
                else 
                   app.ApplyContrastStretchButton.Text = "Apply Contrast Stretch";
                end
                
                %Apply Sharpening to the image
                if app.sharpening
                    app.IRImage = imsharpen(app.IRImage);
                    app.ApplySharpeningButton.Text = "Sharpening Applied";
                else 
                    app.ApplySharpeningButton.Text = "Apply Sharpening";
                end
                
                %Detect Image Edges.
                if app.detectEdges
                    %Image Detection requires converting the image to grayscale first
                    app.IRImage = rgb2gray(app.IRImage);
                    
                    %MATLAB uses the algorithm specified in the Edge Detection Algorithms List Box to detect edges
                    app.IRImage = edge(app.IRImage, app.EdgeDetectionAlgorithmsListBox.Value);
                    app.ApplyEdgeDetectionButton.Text = "Edge Detection Applied";
                else
                    app.ApplyEdgeDetectionButton.Text = "Apply Edge Detection";
                end
                
                %Displays the original (or edited) IR image in the UIAxes
                %imshow(app.IRImage, 'parent', app.ThermalImage, 'XData', [1 app.ThermalImage.Position(3)], 'YData', [1 app.ThermalImage.Position(4)]);
                app.Image.Position(3) = 640;
                app.Image.Position(4) = 480;
                app.Image.ImageSource = app.IRImage;
                
                %Apply a mask to the temperature data
                if app.enableROI
                    
                    %maskedData is a 1D array of temperature elements that lie within the ROI
                    %TempArray is a 2D array of temperature values with elements that lie outside of the ROI set to -500000
                    [app.maskedData,app.TempArray] = ToolkitFunctions.GetROI(app.TempArray, app.ROIPoints.Data(:,2), app.ROIPoints.Data(:,3));
                
                end
                
                %Graph the point specified by the X and Y Edit boxes
                if app.trackPointTemp    
                    
                    %MATLAB begins array indices at 0, so we add one to enable coordinates of (0,0)
                    currentTemp = double(app.TempArray(app.Y.Value, app.X.Value));
                    
                    %currentTempArray is an array of the 10 most recent current temps
                    %This line removes the oldest element in the array and appends the current temp
                    app.currentTempArray = [app.currentTempArray(2:10), currentTemp];
                    
                    %Updates the graph with the new array
                    addpoints(app.pointLine, 0:9, app.currentTempArray);
                    app.CurrentTemp.Value = currentTemp;
                end
                
                %The alarm function needs new data every time that it is run
                if app.alarmEnable
                  
                    if app.enableROI
                        
                        %If the ROI is enabled, send the 1D array of Temperatures within the ROI 
                        %so we don't need to worry about elements with a value of -500000 
                        [alarm, minTemp, maxTemp] = ToolkitFunctions.TempAlarm(app.maskedData, app.MinTempThreshold.Value, app.MaxTempThreshold.Value);
                   
                    else
                        
                        %Send the normal 2D array of temperatures
                        [alarm, minTemp, maxTemp] = ToolkitFunctions.TempAlarm(app.TempArray, app.MinTempThreshold.Value, app.MaxTempThreshold.Value);
                    end
                    
                    
                    if alarm
                        %If the alarm is activated, set the alarm lamp color to green
                        app.AlarmLamp.Color = [0 1 0];
                    else
                        %If the alarm is not activated, set the alarm lamp color back to red
                        app.AlarmLamp.Color = [1 0 0];
                    end
                    
                    app.MinTemp.Value = minTemp;
                    app.MaxTemp.Value = maxTemp;
                end
               
                %Plots the 10 most recent min and max temperautres
                if app.trackMinMaxTemp
                   if app.enableROI
                        %Send the 1D array of elements that lie within the ROI
                        [minVal, maxVal] = ToolkitFunctions.GetMinMax(app.maskedData);
                   else
                       %Send the entire 2D matrix of temperatures
                       [minVal, maxVal] = ToolkitFunctions.GetMinMax(app.TempArray);
                   end
                   
                   %Delete the oldest min/max temperature and append the current min/max temperature
                   app.maxArray = [app.maxArray(2:10), maxVal];
                   app.minArray = [app.minArray(2:10), minVal];
                   
                   %Update the graph
                   addpoints(app.maxLine, 0:9, app.maxArray);
                   addpoints(app.minLine, 0:9, app.minArray);
                                      
                   
                end
                
                %The Temperature Threshold Trigger is called every loop iteration to update its input data
                if app.tempTriggerEnable
                    tempTriggerFilePath = strcat(app.TempTriggerFilePath.Value, '\', app.TempTriggerFileName.Value);
                    
                    if app.enableROI
                        %Send the 1D array of temperature elements within the ROI
                        tempTriggerActivated = ToolkitFunctions.TempTrigger(tempTriggerFilePath, app.TempTriggerInterval.Value, ...
                                                                            app.TempTriggerNumCaptures.Value, app.TempTriggerMinTempThreshold.Value,...
                                                                            app.TempTriggerMaxTempThreshold.Value, app.maskedData);
                    else
                         %Send the entire 2D matrix of temperatures
                         tempTriggerActivated = ToolkitFunctions.TempTrigger(tempTriggerFilePath, app.TempTriggerInterval.Value, ...
                                                                            app.TempTriggerNumCaptures.Value, app.TempTriggerMinTempThreshold.Value,...
                                                                            app.TempTriggerMaxTempThreshold.Value, app.TempArray);
                    end
                    
                    %Returns true if the temperature crossed the threshold and the trigger activated
                    if tempTriggerActivated
                        app.tempTriggerEnable = false;
                        app.EnableTempTriggerButton.Text = "Enable Temp Trigger";
                    end
                end
                
                %Provides feedback on the Relative Time Trigger
                if app.relativeTimeTriggerEnable
                    if isempty(timerfind('Name', 'Relative Timer'))
                        app.EnableRelativeTimeTriggerButton.Text = 'Enable Relative Time Trigger';
                        app.relativeTimeTriggerEnable = false;
                    end
                end
                
                %Provides feedback on the Absolute Time Trigger
                if app.absoluteTimeTriggerEnable
                    if isempty(timerfind('Name', 'Absolute Timer'))
                        app.EnableAbsoluteTimeTriggerButton.Text = 'Enable Absolute Time Trigger';
                        app.absoluteTimeTriggerEnable = false;
                    end
                end
                
                %Provides feedback on the Boolean Trigger
                if app.booleanTriggerEnable;
                    if isempty(timerfind('Name', 'Boolean Timer'))
                        app.EnableBooleanTriggerButton.Text = 'Enable Boolean Trigger';
                        app.booleanTriggerEnable = false;
                    end
                end
                 
            catch E
                msg = E;
                disp(msg);
            end    
        end
    
        
        %Creates the Streaming Loop Timer
        function StartStreamTimer(app, ~)
            t = timer('TimerFcn', {@app.FrameCallback}, 'ExecutionMode', 'fixedSpacing', 'Period', 0.1);
            start(t);
        end
        
        function results = addPoint(app, cursorPositionX, cursorPositionY)
            %Add point to the ROI List
            
            %Get the point to be added
            nextPoint = [cursorPositionX, cursorPositionY];
            %Get the points already in the ROI List
            previousPoints = app.ROIPoints.Data;
            %The first column should be ascending numbers (1,2,3...)
            %So find the previous number
            lastNum = max(previousPoints,[],1);
            
            %If the ROI List is empty, the lastNum becomes 0
            if isempty(lastNum)
                lastNum = 0;
            else
                lastNum = lastNum(1);
            end
            %Append the lastNum + 1 before the next point array to create a 1X3 array
            nextPoint = [lastNum + 1, nextPoint];
            
            %Add the next point to the array of points
            appendedPoints = [previousPoints; nextPoint];
            
            %Update the items in the Delete Points drop down
            rowIndices = appendedPoints(:,1);
            rowIndices = num2str(rowIndices);
            rowNums = cellstr(rowIndices);
            app.RowNumToBeDeleted.Items = rowNums;
            %Update the ROI Points Table
            app.ROIPoints.Data = appendedPoints;
        end
    end
    
    
    methods (Access = public)
        %Used to provide feedback when the recording is completed
        function TriggerCallback(~,~,~, app)
            app.RecordIS3VideoButton.Text = 'Record IS3 Video';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Load the required .DLL files
            ToolkitFunctions.LoadAssemblies();
            import System.Windows.Forms.*
            %Update the absolute time fields to the current time so that the user does not have to enter every field manually for an Absolute Time Trigger
            [year, month, day, hour, minute, second] = datevec(datetime('now'));
            app.AbsoluteTimeTriggerYear.Value = year;
            app.AbsoluteTimeTriggerMonth.Value = month;
            app.AbsoluteTimeTriggerDay.Value = day;
            app.AbsoluteTimeTriggerHour.Value = hour;
            app.AbsoluteTimeTriggerMinute.Value = minute;
            app.AbsoluteTimeTriggerSecond.Value = second;
            
            %Initialize the arrays used in plotting the current, min, and max temperatures
            app.maxArray = zeros(10);
            app.minArray = zeros(10);
            app.currentTempArray = zeros(10);


        end

        % Button pushed function: DiscoverDevices
        function DiscoverDevicesPushed(app, event)
            %Begin searching for cameras
            app.AvailableCameras.Items = "Starting";
            availableCameras = ToolkitFunctions.DiscoverDevices();
            app.AvailableCameras.Items = availableCameras;
        end

        % Button pushed function: StartStream
        function StartStreamButtonPushed(app, event)
            if ~isempty(app.AvailableCameras.Value)
                %Connect to the camera and start a stream if the selected value is not empty
                ToolkitFunctions.SelectDevice(app.AvailableCameras.Value);
                ToolkitFunctions.StartStream();
                app.StartStreamTimer();
            else
                disp("Empty");
            end
        end

        % Button pushed function: StopStreamButton
        function StopStreamButtonPushed(app, event)
            %Delete all timers. This stops the streaming loop
            delete(timerfind);
            ToolkitFunctions.StopStream();
            %Reset the Available Cameras to empty
            app.AvailableCameras.Items = "";
            
        end

        % Button pushed function: ApplyContrastStretchButton
        function ApplyContrastStretchButtonPushed(app, event)
            %Logic for enabling and disabling Contrast Stretch
            if app.contrastStretch
                app.contrastStretch = false;
            else 
                app.contrastStretch = true;
            end
        end

        % Button pushed function: ApplySharpeningButton
        function ApplySharpeningButtonPushed(app, event)
            %Logic for enabling and disabling Image Sharpening 
            if app.sharpening
                app.sharpening = false;
            else 
                app.sharpening = true;
            end
        end

        % Button pushed function: ApplyEdgeDetectionButton
        function ApplyEdgeDetectionButtonPushed(app, event)
            %Logic for enabling and disabling Edge Detection
            if app.detectEdges
                app.detectEdges = false;
            else
                app.detectEdges = true;
            end
        end

        % Button pushed function: TrackPointTemperatureButton
        function TrackPointTemperatureButtonPushed(app, event)
            %Disable plotting of the current point temperature
            if app.trackPointTemp
                app.trackPointTemp = false;
                app.TrackPointTemperatureButton.Text = 'Track Point Temp';
                delete(app.pointLine);
                clear app.pointLine;
                drawnow;
            else 
                %Enable plotting of the current point temperature
                app.trackPointTemp = true;
                app.TrackPointTemperatureButton.Text = 'Tracking Point Temp';
                app.pointLine = animatedline(app.TemperatureTracker, 'MaximumNumPoints', 10, 'Color', 'blue');
            end
        end

        % Button pushed function: TrackMinMaxTempButton
        function TrackMinMaxTempButtonPushed(app, event)
            
            if app.trackMinMaxTemp
                %Disable plotting of the min and max temperatures
                app.trackMinMaxTemp = false;
                app.TrackMinMaxTempButton.Text = 'Track Min/Max Temp';
                delete(app.maxLine);
                delete(app.minLine);
                clear app.maxLine;
                clear app.minLine;
                drawnow;
            else
                %Enable plotting of the min and max temperatures
                app.trackMinMaxTemp = true;
                app.TrackMinMaxTempButton.Text = 'Tracking Min/Max Temp';
                app.maxLine = animatedline(app.TemperatureTracker, 'MaximumNumPoints',10, 'Color', 'green');
                app.minLine = animatedline(app.TemperatureTracker, 'MaximumNumPoints', 10, 'Color', 'red');
                
            end
        end

        % Value changing function: FocusDistance
        function FocusDistanceValueChanging(app, event)
            %The Value Changing Callback allows the user 
            %to see the focus change as they are dragging the 
            %slider rather than when they let go of the mouse button
            changingValue = event.Value;
            ToolkitFunctions.AdjustFocus(changingValue);
        end

        % Button pushed function: SendNUCCommandButton
        function SendNUCCommandButtonPushed(app, event)
            %Send a command to the camera to Enable the NUC functionality
            if app.NUCCommands.Value == "Enable NUC"
                ToolkitFunctions.EnableNUC();
            end
            
            %Send a command to the camera to Disable the NUC functionality
            if app.NUCCommands.Value == "Disable NUC"
                ToolkitFunctions.DisableNUC();
            end
            
            %Send a command to the camera to Manually fire a NUC
            if app.NUCCommands.Value == "Fire NUC"
                ToolkitFunctions.FireNUC();
            end
            
        end

        % Button pushed function: TakeIS2ScreenshotButton
        function TakeIS2ScreenshotButtonPushed(app, event)
            %Sends a command to the camera to save a .IS2 image to disk at the location specified
            screenshotPath = strcat(app.ScreenshotPath.Value, '\', app.ScreenshotFileName.Value);
            ToolkitFunctions.CaptureImage(screenshotPath);
        end

        % Button pushed function: BrowseScreenshotButton
        function BrowseScreenshotButtonPushed(app, event)
            %Opens an explorer window to allow the user to select a folder to store the .IS2 image
            filePath = uigetdir();
            if exist(filePath)
                app.ScreenshotPath.Value = filePath;
            else
                app.ScreenshotPath.Value = "Error: Not a Valid Path";
            end
        end

        % Button pushed function: AdjustRangeButton
        function AdjustRangeButtonPushed(app, event)
            %Sends a command to the camera to set the range to -4°F-176°F
            if app.SelectedRange.Value == "First Range"
                ToolkitFunctions.SetFirstRange();
            end
            %Sends a command to the camera to set the range to -4°F-2192°F
            if app.SelectedRange.Value == "Second Range"
                ToolkitFunctions.SetSecondRange();
            end
        end

        % Button pushed function: ConfigureEBTButton
        function ConfigureEBTButtonPushed(app, event)
            %Allows the user to send a command to the camera to configure the Emissivity and Transmissivity Coefficients, as well as the Background Temp
            ToolkitFunctions.SetEBT(app.EBTEmissivity.Value, app.EBTBGTemp.Value, app.EBTTempUnits.Value, app.EBTTransmission.Value);
        end

        % Button pushed function: EnableAlarmButton
        function EnableAlarmButtonPushed(app, event)
            %Logic to enable and disable the alarm functionality
            if app.alarmEnable
                app.alarmEnable = false;
                app.EnableAlarmButton.Text = "Enable Alarm";
                %Set the Alarm Lamp Color to Red when the Alarm is disabled
                app.AlarmLamp.Color = [1 0 0];
            else
                app.alarmEnable = true;
                app.EnableAlarmButton.Text = "Alarm Enabled";
            end
        end

        % Button pushed function: BrowseRecordButton
        function BrowseRecordButtonPushed(app, event)
            %Opens an explorer window to allow the user to select a folder to store the .IS3 recording
            filePath = uigetdir();
            if exist(filePath)
                app.RecordPath.Value = filePath;
            else
                app.RecordPath.Value = "Error: Not a Valid Path";
            end
        end

        % Button pushed function: RecordIS3VideoButton
        function RecordIS3VideoButtonPushed(app, event)
            %Sends a command to the camera to save a recording (.IS3 File) to disk
            ToolkitFunctions.RecordMovie(app.RecordPath.Value, app.RecordFileName.Value, app.MovieLength.Value);
            movieLength = app.MovieLength.Value / 1000.0;
            
            %The following timer is used to provide feedback to the user that the recording has completed
            recordingTimer = timer;
            recordingTimer.ExecutionMode = 'singleShot';
            recordingTimer.Name = 'Recording Timer';
            recordingTimer.StartDelay = movieLength;
            recordingTimer.TimerFcn = {@app.TriggerCallback, app};
            start(recordingTimer);
            app.RecordIS3VideoButton.Text = 'Recording in Progress';
            
        end

        % Button pushed function: BrowseBooleanTriggerButton
        function BrowseBooleanTriggerButtonPushed(app, event)
            %Opens an explorer window to allow the user to select a folder to store the images from the trigger
            filePath = uigetdir();
            if exist(filePath)
                app.BooleanTriggerPath.Value = filePath;
            else
                app.BooleanTriggerPath.Value = "Error: Not a Valid Path";
            end
        end

        % Button pushed function: EnableBooleanTriggerButton
        function EnableBooleanTriggerButtonPushed(app, event)
            %Sends a command to start the Boolean Trigger
            filePath = strcat(app.BooleanTriggerPath.Value, '\', app.BooleanTriggerFileName.Value);
            ToolkitFunctions.BooleanTrigger(filePath, app.BooleanTriggerInterval.Value, app.BooleanTriggerNumCaptures.Value, true);
            app.EnableBooleanTriggerButton.Text = 'Trigger in Progress';
            app.booleanTriggerEnable = true;
        end

        % Button pushed function: EnableTempTriggerButton
        function EnableTempTriggerButtonPushed(app, event)
            %Logic to enable and disable the Temperature Threshold Trigger
            if app.tempTriggerEnable
                app.EnableTempTriggerButton.Text = "Enable Temp Trigger";
                app.tempTriggerEnable = false;
            else
                app.EnableTempTriggerButton.Text = "Temp Trigger Enabled";
                app.tempTriggerEnable = true;
            end
        end

        % Button pushed function: TempTriggerBrowseButton
        function TempTriggerBrowseButtonPushed(app, event)
            %Opens an explorer window to allow the user to select a folder to store the images from the trigger
            filePath = uigetdir();
            if exist(filePath)
                app.TempTriggerFilePath.Value = filePath;
            else
                app.TempTriggerFilePath.Value = "Error: Not a Valid Path";
            end
        end

        % Button pushed function: RelativeTimeTriggerBrowse
        function RelativeTimeTriggerBrowseButtonPushed(app, event)
            %Opens an explorer window to allow the user to select a folder to store the images from the trigger
            filePath = uigetdir();
            if exist(filePath)
                app.RelativeTimeTriggerFilePath.Value = filePath;
            else
                app.RelativeTimeTriggerFilePath.Value = "Error: Not a Valid Path";
            end   
        end

        % Button pushed function: EnableRelativeTimeTriggerButton
        function EnableRelativeTimeTriggerButtonPushed(app, event)
            %Activates the Relative Time Trigger
            filePath = strcat(app.RelativeTimeTriggerFilePath.Value, '\', app.RelativeTimeTriggerFileName.Value);
            ToolkitFunctions.RelativeTimeTrigger(filePath, app.RelativeTimeTriggerInterval.Value,...
                                                 app.RelativeTimeTriggerNumCaptures.Value, app.RelativeTimeTriggerNummS.Value);
             app.relativeTimeTriggerEnable = true;
             app.EnableRelativeTimeTriggerButton.Text = 'Trigger In Progress';
            
        end

        % Button pushed function: AbsoluteTimeTriggerBrowse
        function AbsoluteTimeTriggerBrowseButtonPushed(app, event)
            %Opens an explorer window to allow the user to select a folder to store the images from the trigger
            filePath = uigetdir();
            if exist(filePath)
                app.AbsoluteTimeTriggerFilePath.Value = filePath;
            else
                app.AbsoluteTimeTriggerFilePath.Value = "Error: Not a Valid Path";
            end
        end

        % Button pushed function: EnableAbsoluteTimeTriggerButton
        function EnableAbsoluteTimeTriggerButtonPushed(app, event)
            %Activates the Absolute Time Trigger
            filePath = strcat(app.AbsoluteTimeTriggerFilePath.Value, '\', app.AbsoluteTimeTriggerFileName.Value);
            year = app.AbsoluteTimeTriggerYear.Value;
            month = app.AbsoluteTimeTriggerMonth.Value;
            day = app.AbsoluteTimeTriggerDay.Value;
            hour = app.AbsoluteTimeTriggerHour.Value;
            minute = app.AbsoluteTimeTriggerMinute.Value;
            second = app.AbsoluteTimeTriggerSecond.Value;
            triggerStartTime = [year, month, day, hour, minute, second];
            
            %Only activate the trigger if the specified time has not happened yet
            %This function will only work if the specified time is fewer than 25 days from
            %the time this trigger is activated
            if datetime(triggerStartTime) > datetime('now')
                ToolkitFunctions.AbsoluteTimeTrigger(filePath, app.AbsoluteTimeTriggerInterval.Value, app.AbsoluteTimeTriggerNumCaptures.Value, triggerStartTime);
                app.absoluteTimeTriggerEnable = true;
                app.EnableAbsoluteTimeTriggerButton.Text = 'Trigger In Progress';
            end
            
        end

        % Button pushed function: AddROIPointsButton
        function AddROIPointsButtonPushed(app, event)
            if app.addROIPoints 
                app.addROIPoints = false;
                app.AddROIPointsButton.Text = 'Add ROI Points';
            else
                app.addROIPoints = true;
                app.AddROIPointsButton.Text = 'Click the Image';
            end
        end

        % Button pushed function: EnableROIButton
        function EnableROIButtonPushed(app, event)
            %Logic to Enable and Disable the ROI
            if app.enableROI
                app.enableROI = false;
                app.EnableROIButton.Text = "Enable ROI";
            else
                app.enableROI = true;
                app.EnableROIButton.Text = "ROI Enabled";
            end
        end

        % Button pushed function: DeletePointButton
        function DeletePointButtonPushed(app, event)
            %Delete a point from the table
            
            %Get the point to be deleted
            row = str2num(app.RowNumToBeDeleted.Value);
            app.ROIPoints.Data(row,:) = [];
            
            %Get the number of points in the ROI Points Table
            rowIndices = app.ROIPoints.Data(:,1);
            numElements = size(rowIndices);
            
            %Create the new first column of the ROI Points Table (1,2,3...)
            rowIndices = 1:numElements;
            
            %Update the ROI Points Table
            app.ROIPoints.Data(:,1) = rowIndices;
            
            %Update the items in the Delete Points Drop Down
            rowIndices = app.ROIPoints.Data(:,1);
            rowIndices = num2str(rowIndices);
            rowNums = cellstr(rowIndices);
            app.RowNumToBeDeleted.Items = rowNums;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %Close the app and clear all the variables
            delete(app) 
            clear;
            
        end

        % Button pushed function: ExporttoCSVButton
        function ExporttoCSVButtonPushed(app, event)
            %Writes the Matrix of Temperatures to a .CSV file
            filePath = strcat(app.CSVFilePath.Value, '\', app.CSVFileName.Value, '.csv');
            csvwrite(filePath, app.TempArray);
        end

        % Button pushed function: CSVBrowseButton
        function CSVBrowseButtonPushed(app, event)
            %Opens an explorer window to allow the user to select a folder to store the .CSV file
            filePath = uigetdir();
            if exist(filePath)
                app.CSVFilePath.Value = filePath;
            else
                app.CSVFilePath.Value = "Error: Not a Valid Path";
            end
            
            %All below code is MATLAB generated and sets up the GUI interface
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            netPoint = System.Windows.Forms.Cursor.Position;
            screenSize = get(0, 'screensize');
            cursorPositionX = cast(netPoint.X - event.Source.Position(1) - event.Source.Parent.Position(1) + 1, 'double');
            cursorPositionY = cast(event.Source.Parent.Position(2) + event.Source.Position(2) + event.Source.Position(4) - screenSize(4) + netPoint.Y +1, 'double');
            if(cursorPositionX < 1)
                cursorPositionX = 1;
            end
            if (cursorPositionX > event.Source.Position(3))
                cursorPositionX = event.Source.Position(3);

            end
            if (cursorPositionY < 1)
                cursorPositionY = 1;
            end
            if (cursorPositionY > event.Source.Position(4))
                cursorPositionY = event.Source.Position(4);
            end
            if app.addROIPoints
                app.addPoint(cursorPositionX, cursorPositionY);
            
            else
                app.X.Value = cursorPositionX;
                app.Y.Value = cursorPositionY;
            end
        
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ThermalImage = uiaxes(app.UIFigure);
            title(app.ThermalImage, 'Thermal Image')
            app.ThermalImage.XLim = [0 640];
            app.ThermalImage.YLim = [0 480];
            app.ThermalImage.XTick = [];
            app.ThermalImage.XTickLabel = '';
            app.ThermalImage.YTick = [];
            app.ThermalImage.YTickLabel = '';
            app.ThermalImage.Visible = 'off';
            app.TemperatureTracker = uiaxes(app.UIFigure);
            title(app.TemperatureTracker, 'Temperature Tracker')
            xlabel(app.TemperatureTracker, 'X')
            ylabel(app.TemperatureTracker, 'Y')
            app.TemperatureTracker.LineStyleOrder = {'-'};
            app.DiscoverDevices = uibutton(app.UIFigure, 'push');
            app.DiscoverDevices.ButtonPushedFcn = createCallbackFcn(app, @DiscoverDevicesPushed, true);
            app.DiscoverDevices.Text = 'Discover Devices';
            app.AvailableCamerasLabel = uilabel(app.UIFigure);
            app.AvailableCamerasLabel.Text = 'Available Cameras';
            app.AvailableCameras = uilistbox(app.UIFigure);
            app.AvailableCameras.Items = {};
            app.AvailableCameras.Tooltip = {'Sobel --good (standard)'; 'Canny --noisy'; 'Prewitt --nice'};
            app.AvailableCameras.Value = {};
            app.StartStream = uibutton(app.UIFigure, 'push');
            app.StartStream.ButtonPushedFcn = createCallbackFcn(app, @StartStreamButtonPushed, true);
            app.StartStream.Text = 'Start Stream';
            app.StopStreamButton = uibutton(app.UIFigure, 'push');
            app.StopStreamButton.ButtonPushedFcn = createCallbackFcn(app, @StopStreamButtonPushed, true);
            app.StopStreamButton.Text = 'Stop Stream';
            app.ApplyContrastStretchButton = uibutton(app.UIFigure, 'push');
            app.ApplyContrastStretchButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyContrastStretchButtonPushed, true);
            app.ApplyContrastStretchButton.Text = 'Apply Contrast Stretch';
            app.ApplySharpeningButton = uibutton(app.UIFigure, 'push');
            app.ApplySharpeningButton.ButtonPushedFcn = createCallbackFcn(app, @ApplySharpeningButtonPushed, true);
            app.ApplySharpeningButton.Text = 'Apply Sharpening';
            app.EdgeDetectionAlgorithmsListBoxLabel = uilabel(app.UIFigure);
            app.EdgeDetectionAlgorithmsListBoxLabel.Text = 'Edge Detection Algorithms';
            app.EdgeDetectionAlgorithmsListBox = uilistbox(app.UIFigure);
            app.EdgeDetectionAlgorithmsListBox.Items = {'Sobel', 'Prewitt', 'log', 'zerocross', 'Canny', 'approxcanny'};
            app.EdgeDetectionAlgorithmsListBox.Value = 'Sobel';
            app.ApplyEdgeDetectionButton = uibutton(app.UIFigure, 'push');
            app.ApplyEdgeDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyEdgeDetectionButtonPushed, true);
            app.ApplyEdgeDetectionButton.Text = 'Apply Edge Detection';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X';
            app.X = uieditfield(app.UIFigure, 'numeric');
            app.X.Limits = [0 640];
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.Y = uieditfield(app.UIFigure, 'numeric');
            app.Y.Limits = [0 480];
            app.TrackPointTemperatureButton = uibutton(app.UIFigure, 'push');
            app.TrackPointTemperatureButton.ButtonPushedFcn = createCallbackFcn(app, @TrackPointTemperatureButtonPushed, true);
            app.TrackPointTemperatureButton.Text = 'Track Point Temperature';
            app.TempEditFieldLabel = uilabel(app.UIFigure);
            app.TempEditFieldLabel.Text = 'Temp';
            app.CurrentTemp = uieditfield(app.UIFigure, 'numeric');
            app.TrackMinMaxTempButton = uibutton(app.UIFigure, 'push');
            app.TrackMinMaxTempButton.ButtonPushedFcn = createCallbackFcn(app, @TrackMinMaxTempButtonPushed, true);
            app.TrackMinMaxTempButton.Text = 'Track Min/Max Temp';
            app.FocusDistancemmSliderLabel = uilabel(app.UIFigure);
            app.FocusDistancemmSliderLabel.Text = 'Focus Distance (mm)';
            app.FocusDistance = uislider(app.UIFigure);
            app.FocusDistance.Limits = [0 1000];
            app.FocusDistance.MajorTicks = [0 250 500 750 1000];
            app.FocusDistance.ValueChangingFcn = createCallbackFcn(app, @FocusDistanceValueChanging, true);
            app.NUCControlDropDownLabel = uilabel(app.UIFigure);
            app.NUCControlDropDownLabel.Text = 'NUC Control';
            app.NUCCommands = uidropdown(app.UIFigure);
            app.NUCCommands.Items = {'Enable NUC', 'Disable NUC', 'Fire NUC'};
            app.NUCCommands.Value = 'Enable NUC';
            app.SendNUCCommandButton = uibutton(app.UIFigure, 'push');
            app.SendNUCCommandButton.ButtonPushedFcn = createCallbackFcn(app, @SendNUCCommandButtonPushed, true);
            app.SendNUCCommandButton.Text = 'Send NUC Command';
            app.ScreenshotPathLabel = uilabel(app.UIFigure);
            app.ScreenshotPathLabel.Text = 'Screenshot Path';
            app.ScreenshotPath = uieditfield(app.UIFigure, 'text');
            app.TakeIS2ScreenshotButton = uibutton(app.UIFigure, 'push');
            app.TakeIS2ScreenshotButton.ButtonPushedFcn = createCallbackFcn(app, @TakeIS2ScreenshotButtonPushed, true);
            app.TakeIS2ScreenshotButton.Text = 'Take IS2 Screenshot';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.ScreenshotFileName = uieditfield(app.UIFigure, 'text');
            app.ScreenshotFileName.Value = 'Screenshot';
            app.BrowseScreenshotButton = uibutton(app.UIFigure, 'push');
            app.BrowseScreenshotButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseScreenshotButtonPushed, true);
            app.BrowseScreenshotButton.Text = 'Browse';
            app.SelectRangeDropDownLabel = uilabel(app.UIFigure);
            app.SelectRangeDropDownLabel.Text = 'Select Range';
            app.SelectedRange = uidropdown(app.UIFigure);
            app.SelectedRange.Items = {'First Range', 'Second Range'};
            app.SelectedRange.Tooltip = {'First Range:'; '-4°F-176°F'; ''; 'Second Range:'; '-4°F-2192°F'; ''};
            app.SelectedRange.Value = 'First Range';
            app.AdjustRangeButton = uibutton(app.UIFigure, 'push');
            app.AdjustRangeButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustRangeButtonPushed, true);
            app.AdjustRangeButton.Text = 'Adjust Range';
            app.TemperatureUnitsDropDownLabel = uilabel(app.UIFigure);
            app.TemperatureUnitsDropDownLabel.Text = 'Temperature Units';
            app.TemperatureUnits = uidropdown(app.UIFigure);
            app.TemperatureUnits.Items = {'Celsius', 'Fahrenheit', 'Kelvin'};
            app.TemperatureUnits.Value = 'Celsius';
            app.EBTConfigurationPanel = uipanel(app.UIFigure);
            app.EBTConfigurationPanel.Title = '         EBT Configuration';
            app.EmissivityEditFieldLabel = uilabel(app.EBTConfigurationPanel);
            app.EmissivityEditFieldLabel.Text = 'Emissivity';
            app.EBTEmissivity = uieditfield(app.EBTConfigurationPanel, 'numeric');
            app.EBTEmissivity.Limits = [0 1];
            app.BGTempEditFieldLabel = uilabel(app.EBTConfigurationPanel);
            app.BGTempEditFieldLabel.Text = 'BG Temp';
            app.EBTBGTemp = uieditfield(app.EBTConfigurationPanel, 'numeric');
            app.TransmissionEditFieldLabel = uilabel(app.EBTConfigurationPanel);
            app.TransmissionEditFieldLabel.Text = 'Transmission';
            app.EBTTransmission = uieditfield(app.EBTConfigurationPanel, 'numeric');
            app.EBTTransmission.Limits = [0 100];
            app.TemperatureUnitsDropDownLabel_2 = uilabel(app.EBTConfigurationPanel);
            app.TemperatureUnitsDropDownLabel_2.Text = 'Temperature Units';
            app.EBTTempUnits = uidropdown(app.EBTConfigurationPanel);
            app.EBTTempUnits.Items = {'Celsius', 'Fahrenheit', 'Kelvin'};
            app.EBTTempUnits.Value = 'Celsius';
            app.ConfigureEBTButton = uibutton(app.EBTConfigurationPanel, 'push');
            app.ConfigureEBTButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureEBTButtonPushed, true);
            app.ConfigureEBTButton.Text = 'Configure EBT';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AlarmTab = uitab(app.TabGroup);
            app.AlarmTab.Title = 'Alarm';
            app.MinTempThresholdEditFieldLabel = uilabel(app.AlarmTab);
            app.MinTempThresholdEditFieldLabel.Text = 'Min Temp Threshold';
            app.MinTempThreshold = uieditfield(app.AlarmTab, 'numeric');
            app.MaxTempThresholdEditFieldLabel = uilabel(app.AlarmTab);
            app.MaxTempThresholdEditFieldLabel.Text = 'Max Temp Threshold';
            app.MaxTempThreshold = uieditfield(app.AlarmTab, 'numeric');
            app.AlarmLampLabel = uilabel(app.AlarmTab);
            app.AlarmLampLabel.Text = 'Alarm';
            app.AlarmLamp = uilamp(app.AlarmTab);
            app.EnableAlarmButton = uibutton(app.AlarmTab, 'push');
            app.EnableAlarmButton.ButtonPushedFcn = createCallbackFcn(app, @EnableAlarmButtonPushed, true);
            app.EnableAlarmButton.Text = 'Enable Alarm';
            app.MinTempEditFieldLabel = uilabel(app.AlarmTab);
            app.MinTempEditFieldLabel.Text = 'Min Temp';
            app.MinTemp = uieditfield(app.AlarmTab, 'numeric');
            app.MaxTempEditFieldLabel = uilabel(app.AlarmTab);
            app.MaxTempEditFieldLabel.Text = 'Max Temp';
            app.MaxTemp = uieditfield(app.AlarmTab, 'numeric');
            app.BooleanTriggerTab = uitab(app.TabGroup);
            app.BooleanTriggerTab.Title = 'Boolean Trigger';
            app.EnableBooleanTriggerButton = uibutton(app.BooleanTriggerTab, 'push');
            app.EnableBooleanTriggerButton.ButtonPushedFcn = createCallbackFcn(app, @EnableBooleanTriggerButtonPushed, true);
            app.EnableBooleanTriggerButton.Text = 'Enable Boolean Trigger';
            app.BrowseBooleanTriggerButton = uibutton(app.BooleanTriggerTab, 'push');
            app.BrowseBooleanTriggerButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseBooleanTriggerButtonPushed, true);
            app.BrowseBooleanTriggerButton.Text = 'Browse';
            app.BooleanTriggerFilePathLabel = uilabel(app.BooleanTriggerTab);
            app.BooleanTriggerFilePathLabel.Text = 'Boolean Trigger File Path';
            app.BooleanTriggerPath = uieditfield(app.BooleanTriggerTab, 'text');
            app.BooleanTriggerFileNameLabel = uilabel(app.BooleanTriggerTab);
            app.BooleanTriggerFileNameLabel.Text = {'Boolean Trigger '; 'File Name'};
            app.BooleanTriggerFileName = uieditfield(app.BooleanTriggerTab, 'text');
            app.IntervalBetweenCapturesmSLabel = uilabel(app.BooleanTriggerTab);
            app.IntervalBetweenCapturesmSLabel.Text = {'Interval Between'; ' Captures (mS) '};
            app.BooleanTriggerInterval = uieditfield(app.BooleanTriggerTab, 'numeric');
            app.BooleanTriggerInterval.Limits = [0 60000];
            app.BooleanTriggerInterval.ValueDisplayFormat = '%11.5g';
            app.NumberofCapturesLabel = uilabel(app.BooleanTriggerTab);
            app.NumberofCapturesLabel.Text = 'Number of Captures';
            app.BooleanTriggerNumCaptures = uieditfield(app.BooleanTriggerTab, 'numeric');
            app.BooleanTriggerNumCaptures.Limits = [0 60000];
            app.BooleanTriggerNumCaptures.ValueDisplayFormat = '%11.5g';
            app.TemperatureThresholdTriggerTab = uitab(app.TabGroup);
            app.TemperatureThresholdTriggerTab.Title = 'Temperature Threshold Trigger';
            app.TempTriggerBrowseButton = uibutton(app.TemperatureThresholdTriggerTab, 'push');
            app.TempTriggerBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @TempTriggerBrowseButtonPushed, true);
            app.TempTriggerBrowseButton.Text = 'Browse';
            app.TemperatureThresholdTriggerFilePathLabel = uilabel(app.TemperatureThresholdTriggerTab);
            app.TemperatureThresholdTriggerFilePathLabel.Text = 'Temperature ThresholdTrigger File Path';
            app.TempTriggerFilePath = uieditfield(app.TemperatureThresholdTriggerTab, 'text');
            app.MinTempThresholdEditFieldLabel_2 = uilabel(app.TemperatureThresholdTriggerTab);
            app.MinTempThresholdEditFieldLabel_2.Text = 'Min Temp Threshold';
            app.TempTriggerMinTempThreshold = uieditfield(app.TemperatureThresholdTriggerTab, 'numeric');
            app.MaxTempThresholdLabel = uilabel(app.TemperatureThresholdTriggerTab);
            app.MaxTempThresholdLabel.Text = 'Max Temp Threshold';
            app.TempTriggerMaxTempThreshold = uieditfield(app.TemperatureThresholdTriggerTab, 'numeric');
            app.IntervalBetweenCapturesmSLabel_2 = uilabel(app.TemperatureThresholdTriggerTab);
            app.IntervalBetweenCapturesmSLabel_2.Text = {'Interval Between '; 'Captures (mS) '};
            app.TempTriggerInterval = uieditfield(app.TemperatureThresholdTriggerTab, 'numeric');
            app.TempTriggerInterval.Limits = [0 60000];
            app.TempTriggerInterval.ValueDisplayFormat = '%11.5g';
            app.NumberofCapturesLabel_2 = uilabel(app.TemperatureThresholdTriggerTab);
            app.NumberofCapturesLabel_2.Text = 'Number of Captures';
            app.TempTriggerNumCaptures = uieditfield(app.TemperatureThresholdTriggerTab, 'numeric');
            app.TempTriggerNumCaptures.Limits = [0 60000];
            app.TempTriggerNumCaptures.ValueDisplayFormat = '%11.5g';
            app.EnableTempTriggerButton = uibutton(app.TemperatureThresholdTriggerTab, 'push');
            app.EnableTempTriggerButton.ButtonPushedFcn = createCallbackFcn(app, @EnableTempTriggerButtonPushed, true);
            app.EnableTempTriggerButton.Text = 'Enable Temp Trigger';
            app.TempTriggerFileNameLabel = uilabel(app.TemperatureThresholdTriggerTab);
            app.TempTriggerFileNameLabel.Text = {'Temp Trigger '; 'File Name'};
            app.TempTriggerFileName = uieditfield(app.TemperatureThresholdTriggerTab, 'text');
            app.RelativeTimeTriggerTab = uitab(app.TabGroup);
            app.RelativeTimeTriggerTab.Title = 'Relative Time Trigger';
            app.RelativeTimeTriggerBrowse = uibutton(app.RelativeTimeTriggerTab, 'push');
            app.RelativeTimeTriggerBrowse.ButtonPushedFcn = createCallbackFcn(app, @RelativeTimeTriggerBrowseButtonPushed, true);
            app.RelativeTimeTriggerBrowse.Text = 'Browse';
            app.RelativeTimeTriggerFilePathLabel = uilabel(app.RelativeTimeTriggerTab);
            app.RelativeTimeTriggerFilePathLabel.Text = 'Relative Time Trigger File Path';
            app.RelativeTimeTriggerFilePath = uieditfield(app.RelativeTimeTriggerTab, 'text');
            app.RelativeTimeTriggerFileNameLabel = uilabel(app.RelativeTimeTriggerTab);
            app.RelativeTimeTriggerFileNameLabel.Text = {'Relative Time'; 'Trigger File Name'};
            app.RelativeTimeTriggerFileName = uieditfield(app.RelativeTimeTriggerTab, 'text');
            app.IntervalBetweenCapturesmSLabel_3 = uilabel(app.RelativeTimeTriggerTab);
            app.IntervalBetweenCapturesmSLabel_3.Text = {'Interval Between '; 'Captures (mS) '};
            app.RelativeTimeTriggerInterval = uieditfield(app.RelativeTimeTriggerTab, 'numeric');
            app.RelativeTimeTriggerInterval.Limits = [0 60000];
            app.RelativeTimeTriggerInterval.ValueDisplayFormat = '%11.5g';
            app.NumberofCapturesLabel_3 = uilabel(app.RelativeTimeTriggerTab);
            app.NumberofCapturesLabel_3.Text = 'Number of Captures';
            app.RelativeTimeTriggerNumCaptures = uieditfield(app.RelativeTimeTriggerTab, 'numeric');
            app.RelativeTimeTriggerNumCaptures.Limits = [0 60000];
            app.RelativeTimeTriggerNumCaptures.ValueDisplayFormat = '%11.5g';
            app.NumberofmStoWaitLabel = uilabel(app.RelativeTimeTriggerTab);
            app.NumberofmStoWaitLabel.Text = {'Number of mS'; 'to Wait'};
            app.RelativeTimeTriggerNummS = uieditfield(app.RelativeTimeTriggerTab, 'numeric');
            app.RelativeTimeTriggerNummS.Limits = [0 60000];
            app.RelativeTimeTriggerNummS.ValueDisplayFormat = '%11.5g';
            app.EnableRelativeTimeTriggerButton = uibutton(app.RelativeTimeTriggerTab, 'push');
            app.EnableRelativeTimeTriggerButton.ButtonPushedFcn = createCallbackFcn(app, @EnableRelativeTimeTriggerButtonPushed, true);
            app.EnableRelativeTimeTriggerButton.Text = 'Enable Relative Time Trigger';
            app.AbsoluteTimeTriggerTab = uitab(app.TabGroup);
            app.AbsoluteTimeTriggerTab.Title = 'Absolute Time Trigger';
            app.AbsoluteTimeTriggerBrowse = uibutton(app.AbsoluteTimeTriggerTab, 'push');
            app.AbsoluteTimeTriggerBrowse.ButtonPushedFcn = createCallbackFcn(app, @AbsoluteTimeTriggerBrowseButtonPushed, true);
            app.AbsoluteTimeTriggerBrowse.Text = 'Browse';
            app.AbsoluteTimeTriggerFilePathLabel = uilabel(app.AbsoluteTimeTriggerTab);
            app.AbsoluteTimeTriggerFilePathLabel.Text = 'Absolute Time Trigger File Path';
            app.AbsoluteTimeTriggerFilePath = uieditfield(app.AbsoluteTimeTriggerTab, 'text');
            app.AbsoluteTimeTriggerFileNameLabel = uilabel(app.AbsoluteTimeTriggerTab);
            app.AbsoluteTimeTriggerFileNameLabel.Text = 'Absolute Time Trigger File Name';
            app.AbsoluteTimeTriggerFileName = uieditfield(app.AbsoluteTimeTriggerTab, 'text');
            app.IntervalBetweenCapturesmSLabel_4 = uilabel(app.AbsoluteTimeTriggerTab);
            app.IntervalBetweenCapturesmSLabel_4.Text = {'Interval Between'; ' Captures (mS) '};
            app.AbsoluteTimeTriggerInterval = uieditfield(app.AbsoluteTimeTriggerTab, 'numeric');
            app.AbsoluteTimeTriggerInterval.Limits = [0 60000];
            app.AbsoluteTimeTriggerInterval.ValueDisplayFormat = '%11.5g';
            app.NumberofCapturesLabel_4 = uilabel(app.AbsoluteTimeTriggerTab);
            app.NumberofCapturesLabel_4.Text = 'Number of Captures';
            app.AbsoluteTimeTriggerNumCaptures = uieditfield(app.AbsoluteTimeTriggerTab, 'numeric');
            app.AbsoluteTimeTriggerNumCaptures.Limits = [0 60000];
            app.AbsoluteTimeTriggerNumCaptures.ValueDisplayFormat = '%11.5g';
            app.EnableAbsoluteTimeTriggerButton = uibutton(app.AbsoluteTimeTriggerTab, 'push');
            app.EnableAbsoluteTimeTriggerButton.ButtonPushedFcn = createCallbackFcn(app, @EnableAbsoluteTimeTriggerButtonPushed, true);
            app.EnableAbsoluteTimeTriggerButton.Text = 'Enable Trigger';
            app.YearLabel = uilabel(app.AbsoluteTimeTriggerTab);
            app.YearLabel.Text = 'Year';
            app.AbsoluteTimeTriggerYear = uieditfield(app.AbsoluteTimeTriggerTab, 'numeric');
            app.AbsoluteTimeTriggerYear.Limits = [2019 60000];
            app.AbsoluteTimeTriggerYear.ValueDisplayFormat = '%11.5g';
            app.AbsoluteTimeTriggerYear.Value = 2019;
            app.MonthLabel = uilabel(app.AbsoluteTimeTriggerTab);
            app.MonthLabel.Text = 'Month';
            app.AbsoluteTimeTriggerMonth = uieditfield(app.AbsoluteTimeTriggerTab, 'numeric');
            app.AbsoluteTimeTriggerMonth.Limits = [0 12];
            app.AbsoluteTimeTriggerMonth.ValueDisplayFormat = '%11.5g';
            app.DayLabel = uilabel(app.AbsoluteTimeTriggerTab);
            app.DayLabel.Text = 'Day';
            app.AbsoluteTimeTriggerDay = uieditfield(app.AbsoluteTimeTriggerTab, 'numeric');
            app.AbsoluteTimeTriggerDay.Limits = [0 31];
            app.AbsoluteTimeTriggerDay.ValueDisplayFormat = '%11.5g';
            app.HourLabel = uilabel(app.AbsoluteTimeTriggerTab);
            app.HourLabel.Text = 'Hour';
            app.AbsoluteTimeTriggerHour = uieditfield(app.AbsoluteTimeTriggerTab, 'numeric');
            app.AbsoluteTimeTriggerHour.Limits = [0 24];
            app.AbsoluteTimeTriggerHour.ValueDisplayFormat = '%11.5g';
            app.MinuteLabel = uilabel(app.AbsoluteTimeTriggerTab);
            app.MinuteLabel.Text = 'Minute';
            app.AbsoluteTimeTriggerMinute = uieditfield(app.AbsoluteTimeTriggerTab, 'numeric');
            app.AbsoluteTimeTriggerMinute.Limits = [0 60];
            app.AbsoluteTimeTriggerMinute.ValueDisplayFormat = '%11.5g';
            app.SecondLabel = uilabel(app.AbsoluteTimeTriggerTab);
            app.SecondLabel.Text = 'Second';
            app.AbsoluteTimeTriggerSecond = uieditfield(app.AbsoluteTimeTriggerTab, 'numeric');
            app.AbsoluteTimeTriggerSecond.Limits = [0 60];
            app.AbsoluteTimeTriggerSecond.RoundFractionalValues = 'on';
            app.AbsoluteTimeTriggerSecond.ValueDisplayFormat = '%11.5g';
            app.WritetoCSVTab = uitab(app.TabGroup);
            app.WritetoCSVTab.Title = 'Write to CSV';
            app.ExporttoCSVButton = uibutton(app.WritetoCSVTab, 'push');
            app.ExporttoCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoCSVButtonPushed, true);
            app.ExporttoCSVButton.Text = 'Export to CSV';
            app.CSVFilePathLabel = uilabel(app.WritetoCSVTab);
            app.CSVFilePathLabel.Text = 'CSV File Path';
            app.CSVFilePath = uieditfield(app.WritetoCSVTab, 'text');
            app.CSVFileNameLabel = uilabel(app.WritetoCSVTab);
            app.CSVFileNameLabel.Text = 'CSV File Name';
            app.CSVFileName = uieditfield(app.WritetoCSVTab, 'text');
            app.CSVFileName.Value = 'TempData';
            app.CSVBrowseButton = uibutton(app.WritetoCSVTab, 'push');
            app.CSVBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @CSVBrowseButtonPushed, true);
            app.CSVBrowseButton.Text = 'Browse';
            app.RecordIS3VideoButton = uibutton(app.UIFigure, 'push');
            app.RecordIS3VideoButton.ButtonPushedFcn = createCallbackFcn(app, @RecordIS3VideoButtonPushed, true);
            app.RecordIS3VideoButton.Text = 'Record IS3 Video';
            app.BrowseRecordButton = uibutton(app.UIFigure, 'push');
            app.BrowseRecordButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseRecordButtonPushed, true);
            app.BrowseRecordButton.Text = 'Browse';
            app.RecordingPathLabel = uilabel(app.UIFigure);
            app.RecordingPathLabel.Text = 'Recording Path';
            app.RecordPath = uieditfield(app.UIFigure, 'text');
            app.FileNameEditFieldLabel_2 = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel_2.Text = 'File Name';
            app.RecordFileName = uieditfield(app.UIFigure, 'text');
            app.RecordFileName.Value = 'Recording';
            app.LengthmSEditFieldLabel = uilabel(app.UIFigure);
            app.LengthmSEditFieldLabel.Text = 'Length (mS)';
            app.MovieLength = uieditfield(app.UIFigure, 'numeric');
            app.MovieLength.Limits = [0 60000];
            app.MovieLength.ValueDisplayFormat = '%11.5g';
            app.AddROIPointsButton = uibutton(app.UIFigure, 'push');
            app.AddROIPointsButton.ButtonPushedFcn = createCallbackFcn(app, @AddROIPointsButtonPushed, true);
            app.AddROIPointsButton.Text = 'Add ROI Points';
            app.ROIPoints = uitable(app.UIFigure);
            app.ROIPoints.ColumnName = {'RowNum'; 'X'; 'Y'};
            app.ROIPoints.RowName = {};
            app.ROIPoints.ColumnEditable = true;
            app.DeletePointButton = uibutton(app.UIFigure, 'push');
            app.DeletePointButton.ButtonPushedFcn = createCallbackFcn(app, @DeletePointButtonPushed, true);
            app.DeletePointButton.Text = 'Delete Point';
            app.EnableROIButton = uibutton(app.UIFigure, 'push');
            app.EnableROIButton.ButtonPushedFcn = createCallbackFcn(app, @EnableROIButtonPushed, true);
            app.EnableROIButton.Text = 'Enable ROI';
            app.RowtoBeDeletedLabel = uilabel(app.UIFigure);
            app.RowtoBeDeletedLabel.Text = 'Row to Be Deleted';
            app.RowNumToBeDeleted = uidropdown(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.ROIPointsLabel = uilabel(app.UIFigure);
            app.ROIPointsLabel.Text = 'ROI Points';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FlukeMatlabToolkit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end